classdef eth2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ImagesetPanel                matlab.ui.container.Panel
        ResponsetimesEditField       matlab.ui.control.EditField
        ResponsetimesEditFieldLabel  matlab.ui.control.Label
        GaptimesEditField            matlab.ui.control.EditField
        GaptimesEditFieldLabel       matlab.ui.control.Label
        SampletimesEditField         matlab.ui.control.EditField
        SampletimesEditFieldLabel    matlab.ui.control.Label
        FixBreaktimesEditField       matlab.ui.control.EditField
        FixBreaktimesEditFieldLabel  matlab.ui.control.Label
        FixationtimesEditField       matlab.ui.control.EditField
        FixationtimesEditFieldLabel  matlab.ui.control.Label
        MaxAcqtimesEditField         matlab.ui.control.EditField
        MaxAcqtimesEditFieldLabel    matlab.ui.control.Label
        GenerateTrialsButton         matlab.ui.control.Button
        LRNcountsEditField           matlab.ui.control.EditField
        LRNcountsLabel               matlab.ui.control.Label
        StatusLabel                  matlab.ui.control.Label
        SelectFolderButton           matlab.ui.control.Button
        FolderSelectedLabel          matlab.ui.control.Label
        RootFolderLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        Imageset % Imageset to be used 
        LRNcounts % 3xN; row 1,2,3 = LeftChg, NoChg, RightChg
        Blocks % blocks of trials
        MaxAcqTimeSec % max acquisition time
        FixTimeSec % Fixation time
        BreakTimeSec % punish time 
        SampTimeSec
        GapTimeSec
        RespTimeSec
    end
    
    methods (Access = private)
        
        function myInitSinglePref(app, group, name, value)
            if ~ispref(group, name)
                addpref(group, name, value);
            end
        end
        
        function myInitPrefs(app)
            app.myInitSinglePref('etholog', 'FolderSelected', pwd());
            app.myInitSinglePref('etholog', 'LRNcounts', [30, 20, 10; 10, 30, 20; 20, 20, 20]');
            app.myInitSinglePref('etholog', 'MaxAcqTimeSec', 2.0);
            app.myInitSinglePref('etholog', 'FixTimeSec', [0.5, 1.0]);
            app.myInitSinglePref('etholog', 'BreakTimeSec', 0.25);
            app.myInitSinglePref('etholog', 'SampTimeSec', 1.5);
            app.myInitSinglePref('etholog', 'GapTimeSec', 0.0);
            app.myInitSinglePref('etholog', 'RespTimeSec', 2.0);
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Imageset = [];
            app.FolderSelectedLabel.Text = 'none selected';
            app.StatusLabel.Text='';
            app.GenerateTrialsButton.Enable = "off";

            % init prefs
            app.myInitPrefs();
            
            % retrieve values for dialog
            app.FolderSelectedLabel.Text = getpref('etholog', 'FolderSelected');
            
            app.LRNcounts = getpref('etholog', 'LRNcounts');
            app.LRNcountsEditField.Value = mat2str(app.LRNcounts);

            app.MaxAcqTimeSec = getpref('etholog', 'MaxAcqTimeSec');
            app.MaxAcqtimesEditField.Value = mat2str(app.MaxAcqTimeSec);

            app.FixTimeSec = getpref('etholog', 'FixTimeSec');
            app.FixationtimesEditField.Value = mat2str(app.FixTimeSec);

            app.BreakTimeSec = getpref('etholog', 'BreakTimeSec');
            app.FixBreaktimesEditField.Value = mat2str(app.BreakTimeSec);
            
            app.SampTimeSec = getpref('etholog', 'SampTimeSec');
            app.SampletimesEditField.Value = mat2str(app.SampTimeSec);
            
            app.GapTimeSec = getpref('etholog', 'GapTimeSec');
            app.GaptimesEditField.Value = mat2str(app.GapTimeSec);

            app.RespTimeSec = getpref('etholog', 'RespTimeSec');
            app.ResponsetimesEditField.Value = mat2str(app.RespTimeSec);
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            % Select folder, then load
            selected = uigetdir([], 'Select Imageset root folder');
            if ischar(selected)
                try
                    app.Imageset = imageset(selected);
                    app.FolderSelectedLabel.Text = selected;
                    app.StatusLabel.Text = sprintf('%d images loaded.', length(app.Imageset.Images));
                    app.GenerateTrialsButton.Enable = "on";
                catch ME
                    fprintf('Error reading imageset from %s:\n%s\n', selected, getReport(ME));
                end 
            else
                fprintf('nothing selected\n');
            end

        end

        % Value changed function: LRNcountsEditField
        function LRNcountsEditFieldValueChanged(app, event)
            value = app.LRNcountsEditField.Value;
            try
                Z = eval(value);
                if size(Z, 1) ~= 3
                    errordlg('LRN counts must eval to a 3xN array. Columns should sum to same value.', 'Input Error');
                else
                    zz=sum(Z);
                    if ~all(zz==zz(1))
                        errordlg('LRN counts must eval to a 3xN array. Columns should sum to same value.', 'Input Error');
                    end
                end
                app.LRNcounts = Z;
                app.StatusLabel.Text = sprintf('%d blocks, %d images per block.', size(Z, 2), sum(Z(:,1)));
            catch ME
                errordlg(ME.message, 'Eval error');
            end

        end

        % Button pushed function: GenerateTrialsButton
        function GenerateTrialsButtonPushed(app, event)
            try
                blocks = generateEthBlocks(app.Imageset.BalancedFileKeys, app.LRNcounts);
                app.StatusLabel.Text = sprintf('%d blocks created.', length(blocks));
                app.Blocks = blocks;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            % save stuff for preferences
            setpref('etholog', 'FolderSelected', app.FolderSelectedLabel.Text);
            setpref('etholog', 'LRNcounts', app.LRNcounts);

            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImagesetPanel = uipanel(app.UIFigure);
            app.ImagesetPanel.Title = 'Imageset';
            app.RootFolderLabel = uilabel(app.ImagesetPanel);
            app.RootFolderLabel.Text = 'Root Folder:';
            app.FolderSelectedLabel = uilabel(app.ImagesetPanel);
            app.SelectFolderButton = uibutton(app.ImagesetPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.StatusLabel = uilabel(app.ImagesetPanel);
            app.StatusLabel.Text = 'Label2';
            app.LRNcountsLabel = uilabel(app.ImagesetPanel);
            app.LRNcountsLabel.Text = 'L-R-N counts';
            app.LRNcountsEditField = uieditfield(app.ImagesetPanel, 'text');
            app.LRNcountsEditField.ValueChangedFcn = createCallbackFcn(app, @LRNcountsEditFieldValueChanged, true);
            app.GenerateTrialsButton = uibutton(app.ImagesetPanel, 'push');
            app.GenerateTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTrialsButtonPushed, true);
            app.GenerateTrialsButton.Text = 'Generate Trials';
            app.MaxAcqtimesEditFieldLabel = uilabel(app.ImagesetPanel);
            app.MaxAcqtimesEditFieldLabel.Text = 'Max Acq time (s)';
            app.MaxAcqtimesEditField = uieditfield(app.ImagesetPanel, 'text');
            app.FixationtimesEditFieldLabel = uilabel(app.ImagesetPanel);
            app.FixationtimesEditFieldLabel.Text = 'Fixation time (s)';
            app.FixationtimesEditField = uieditfield(app.ImagesetPanel, 'text');
            app.FixBreaktimesEditFieldLabel = uilabel(app.ImagesetPanel);
            app.FixBreaktimesEditFieldLabel.Text = 'Fix Break time (s)';
            app.FixBreaktimesEditField = uieditfield(app.ImagesetPanel, 'text');
            app.SampletimesEditFieldLabel = uilabel(app.ImagesetPanel);
            app.SampletimesEditFieldLabel.Text = 'Sample time (s)';
            app.SampletimesEditField = uieditfield(app.ImagesetPanel, 'text');
            app.GaptimesEditFieldLabel = uilabel(app.ImagesetPanel);
            app.GaptimesEditFieldLabel.Text = 'Gap time (s)';
            app.GaptimesEditField = uieditfield(app.ImagesetPanel, 'text');
            app.ResponsetimesEditFieldLabel = uilabel(app.ImagesetPanel);
            app.ResponsetimesEditFieldLabel.Text = 'Response time (s)';
            app.ResponsetimesEditField = uieditfield(app.ImagesetPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eth2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end